classdef ShowSai < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowSaiUIFigure                 matlab.ui.Figure
        StatusLabel                     matlab.ui.control.Label
        CochleagramTabGroup             matlab.ui.container.TabGroup
        MovieCochleagramTab             matlab.ui.container.Tab
        UIAxesForMovieCochleagram       matlab.ui.control.UIAxes
        CompleteCochleagramTab          matlab.ui.container.Tab
        UIAxesForCompleteCochleagram    matlab.ui.control.UIAxes
        PitchogramTabGroup              matlab.ui.container.TabGroup
        MoviePitchogramTab              matlab.ui.container.Tab
        UIAxesForMoviePitchogram        matlab.ui.control.UIAxes
        CompletePitchogramTab           matlab.ui.container.Tab
        UIAxesForCompletePitchogram     matlab.ui.control.UIAxes
        ControlpanelPanel               matlab.ui.container.Panel
        CopyFiguresToSeparateWindowButton  matlab.ui.control.Button
        ResetAxesButton                 matlab.ui.control.Button
        UpperLimitOfFrequencyAxisHzLabel  matlab.ui.control.Label
        UpperLimitOfFrequencyAxisEditField  matlab.ui.control.NumericEditField
        upperlimitoffrequencyaxisLabel  matlab.ui.control.Label
        LowerLimitOfLagAxisHzLabel      matlab.ui.control.Label
        LowerLimitOfLagAxisEditField    matlab.ui.control.NumericEditField
        lowerlimitoflagaxisEditFieldLabel  matlab.ui.control.Label
        RecomputeMoviePitchogramPanel   matlab.ui.container.Panel
        sLabel_2                        matlab.ui.control.Label
        pxLabel                         matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        StretchEditField                matlab.ui.control.NumericEditField
        StretchEditFieldLabel           matlab.ui.control.Label
        ColormapScaleFactorSpinner      matlab.ui.control.Spinner
        ColormapscalefactorLabel        matlab.ui.control.Label
        OpenNewWindowButton             matlab.ui.control.Button
        SegmentStepSizeSpinner          matlab.ui.control.Spinner
        SegmentstepsizeSpinnerLabel     matlab.ui.control.Label
        OpenSummaryFileButton           matlab.ui.control.Button
        SaveActualSegmentButton         matlab.ui.control.Button
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        TimeLabel                       matlab.ui.control.Label
        SegmentSpinner                  matlab.ui.control.Spinner
        SegmentSpinnerLabel             matlab.ui.control.Label
        SegmentSlider                   matlab.ui.control.Slider
        SegmentSliderLabel              matlab.ui.control.Label
        LabelForFilename                matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        UIAxesForSai                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        summary
        colormapMatrix
        colormapScaleFactor
        COLORMAP_SCALE_FACTOR_FOR_SAI = 32;
        COLORMAP_SCALE_FACTOR_FOR_COCHLEAGRAM = 7;
        COLORMAP_SCALE_FACTOR_FOR_PITCHOGRAM = 8.5;
        
        numberOfRowsOfPitchogram
        numberOfRowsToStretchPitchogram
        minimumTimeOfSaiMovieInSeconds
        maximumTimeOfSaiMovieInSeconds
        
        subfolderForSaiSegments
        fullFolderForSaiSegmentsOfAudioFile
        pathToResults
        
        lineToShowPositionInCompleteCochleagram
        lineToShowPositionInCompletePitchogram
    end
    
    properties (Access = public)
        sai
        movieCochleagram
        completeCochleagram
        moviePitchogram
        completePitchogram

    end
    
    methods (Access = private)
        
        function LoadSummaryFile(app)
            app.StatusLabel.Text = 'Load summary file and its components...';
            
            LIST_OF_MATLAB_FILE_TYPES_TO_FILTER = { ...
                '*.mat', 'MAT-files (*.mat)' ...
                };

            [filenameOfAudioFileSaiComputationSummary,selectedPath,isCanceled] = uigetfile( ...
                LIST_OF_MATLAB_FILE_TYPES_TO_FILTER, 'Open summary file',app.pathToResults);
            % uigetfile delivers...
            %   selectedPath = 0, if 'Cancel' was chosen (isCanceled = 0)
            %   selectedPath = 'path\to\file\' with a trailing
            %                  (back)slash, if a file was chosen (isCanceled > 0)
            
            % Get focus back on app
            % https://de.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile#answer_390627
            %drawnow;
            figure(app.ShowSaiUIFigure)
            
            if isCanceled > 0
                % Reason for 'fileparts' usage is because 'uigetfile' delivers
                % the path with a trailing (back)slash, and when
                % 'LoadSummaryFile' is executed again 'uigetfile' throws an
                % error due to wrong format of initial path!
                app.pathToResults = fileparts(selectedPath);
                
                LoadData(app,filenameOfAudioFileSaiComputationSummary)
    
                app.StatusLabel.Text = 'Set-up UI...';
                app.LabelForFilename.Text = app.summary.filenameOfAudioFile;
                app.SegmentSlider.Value = 1;
                app.SegmentSpinner.Value = 1;

                app.colormapMatrix = ReturnColormapMatrix(app.ColormapDropDown.Value);
                app.colormapScaleFactor = app.ColormapScaleFactorSpinner.Value;

                SetVariables(app)
                app.StretchEditField.Value = app.numberOfRowsToStretchPitchogram;
                app.DurationEditField.Value = (app.numberOfRowsOfPitchogram - app.numberOfRowsToStretchPitchogram) / app.sai.frameRateOfSaiMovie;

                SetFolderForSaiSegments(app)
                SetLimitsForUiComponents(app)
                SetLimitsForUiAxesComponents(app,app)
                
                SetAxisForUIAxis(app,app)
                SetValueOfLimitEditFieldsToDefault(app)
                
                UpdateTimeEditField(app,1)
                
                SetColormap(app,app)
                
                ShowSegment(app,app,1)
                ShowCompleteCochleagramAndPitchogram(app,app)
                SetLinesToShowPositionInCompleteGrams(app,app,1)
                
                app.StatusLabel.Text = 'Set-up UI finished.';
                app.StatusLabel.Text = 'Load summary file and its components finished.';
            else
                app.StatusLabel.Text = 'No summary file was selected.';
            end
        end
        
        function LoadData(app,filenameOfAudioFileSaiComputationSummary)
            app.StatusLabel.Text = 'Loading data...';
            cd(app.pathToResults)
            
            app.StatusLabel.Text = ['Loading ',filenameOfAudioFileSaiComputationSummary];
            app.summary = load(fullfile(pwd,filenameOfAudioFileSaiComputationSummary));
            
            app.StatusLabel.Text = ['Loading ',app.summary.filenameOfSaiMatFile];
            app.sai = load(app.summary.fullFilenameOfSaiMatFile);

            app.StatusLabel.Text = ['Loading ',app.summary.filenameOfMovieCochleagramMatFile];
            app.movieCochleagram = load(app.summary.fullFilenameOfMovieCochleagramMatFile);

            app.StatusLabel.Text = ['Loading ',app.summary.filenameOfCompleteCochleagramMatFile];
            app.completeCochleagram = load(app.summary.fullFilenameOfCompleteCochleagramMatFile);

            app.StatusLabel.Text = ['Loading ',app.summary.filenameOfMoviePitchogramMatFile];
            app.moviePitchogram = load(app.summary.fullFilenameOfMoviePitchogramMatFile);

            app.StatusLabel.Text = ['Loading ',app.summary.filenameOfCompletePitchogramMatFile];
            app.completePitchogram = load(app.summary.fullFilenameOfCompletePitchogramMatFile);

            app.StatusLabel.Text = 'Loading data finished.';
        end
        
        function SetVariables(app)
            app.numberOfRowsOfPitchogram = app.moviePitchogram.NUMBER_OF_ROWS_OF_PITCHOGRAM;
            app.numberOfRowsToStretchPitchogram = app.moviePitchogram.NUMBER_OF_ROWS_TO_STRETCH_PITCHOGRAM;
            app.minimumTimeOfSaiMovieInSeconds = 1 / app.sai.frameRateOfSaiMovie;
            app.maximumTimeOfSaiMovieInSeconds = app.sai.totalNumberOfSegments / app.sai.frameRateOfSaiMovie;            
        end
        
        function SetFolderForSaiSegments(app)
            app.subfolderForSaiSegments = 'Analysis';
            [~,nameOfAudioFile,~] = fileparts(app.summary.filenameOfAudioFile);
            nameSuffixDependingOnColormap = ['_',app.ColormapDropDown.Value];
        
            folderForSaiSegmentsOfAudioFile = [nameOfAudioFile,app.summary.nameSuffixDependingOnWavType,nameSuffixDependingOnColormap];
            app.fullFolderForSaiSegmentsOfAudioFile = fullfile(app.subfolderForSaiSegments,folderForSaiSegmentsOfAudioFile);
        end
        
        function SetLimitsForUiComponents(app)
            app.SegmentSlider.Limits = [1 app.sai.totalNumberOfSegments];
            app.SegmentSpinner.Limits = [1 app.sai.totalNumberOfSegments];
        end
        
        function SetValueOfLimitEditFieldsToDefault(app)
            app.LowerLimitOfLagAxisEditField.RoundFractionalValues = true;
            app.LowerLimitOfLagAxisEditField.Value = app.sai.lagAxisInHzOfSaiAndPitchogram(app.UIAxesForCompletePitchogram.XLim(1));
            
            app.UpperLimitOfFrequencyAxisEditField.Value = app.sai.frequencyAxisOfSaiAndCochleagram(app.UIAxesForCompleteCochleagram.YLim(1));
        end

        function SetFrequencyUIAxisForSaiAndCochleagrams(app,appObjectForTargetUi)
            ticksForFrequencyAxisInHz = [ 50 100 200 500 1000 2000 5000 10000 ];
            [~, indicesOfTicksForFrequencyAxis] = min(abs((app.sai.frequencyAxisOfSaiAndCochleagram' - ticksForFrequencyAxisInHz')'));
            ticklabelsForFrequencyAxisInHz = num2cell(ticksForFrequencyAxisInHz);
            
            yticks(appObjectForTargetUi.UIAxesForSai,flip(indicesOfTicksForFrequencyAxis))
            yticklabels(appObjectForTargetUi.UIAxesForSai,flip(ticklabelsForFrequencyAxisInHz))
            yticks(appObjectForTargetUi.UIAxesForMovieCochleagram,flip(indicesOfTicksForFrequencyAxis))
            yticklabels(appObjectForTargetUi.UIAxesForMovieCochleagram,flip(ticklabelsForFrequencyAxisInHz))
            yticks(appObjectForTargetUi.UIAxesForCompleteCochleagram,flip(indicesOfTicksForFrequencyAxis))
            yticklabels(appObjectForTargetUi.UIAxesForCompleteCochleagram,flip(ticklabelsForFrequencyAxisInHz))
        end
        
        function SetLagUIAxisForSaiAndPitchograms(app,appObjectForTargetUi)
            ticksForLagAxisInHz = [ 0.1 0.2 0.5 1 2 5 10 20 50 100 200 500 1000 ];
            [~, indicesOfTicksForLagAxis] = min(abs((app.sai.lagAxisInHzOfSaiAndPitchogram - ticksForLagAxisInHz')'));
            ticklabelsForLagAxisInHz = num2cell(ticksForLagAxisInHz);
            
            xticks(appObjectForTargetUi.UIAxesForSai,indicesOfTicksForLagAxis)
            xticklabels(appObjectForTargetUi.UIAxesForSai,ticklabelsForLagAxisInHz)
            xticks(appObjectForTargetUi.UIAxesForMoviePitchogram,indicesOfTicksForLagAxis)
            xticklabels(appObjectForTargetUi.UIAxesForMoviePitchogram,ticklabelsForLagAxisInHz)
            xticks(appObjectForTargetUi.UIAxesForCompletePitchogram,indicesOfTicksForLagAxis)
            xticklabels(appObjectForTargetUi.UIAxesForCompletePitchogram,ticklabelsForLagAxisInHz)
        end

        function SetTimeUIAxisForMovieCochleagram(app,appObjectForTargetUi)
            indicesOfTicksForTimeAxisOfCochleagram = app.sai.frameRateOfSaiMovie:app.sai.frameRateOfSaiMovie:app.sai.totalWidthOfAllLayers;
            ticklabelsForTimeAxisOfCochleagram = num2cell(indicesOfTicksForTimeAxisOfCochleagram/app.sai.frameRateOfSaiMovie);
            
            xticks(appObjectForTargetUi.UIAxesForMovieCochleagram,indicesOfTicksForTimeAxisOfCochleagram)
            xticklabels(appObjectForTargetUi.UIAxesForMovieCochleagram,ticklabelsForTimeAxisOfCochleagram)
        end
        
        function SetTimeUIAxisForMoviePitchogram(app,appObjectForTargetUi)
            indicesOfTicksForTimeAxisOfPitchogram = app.numberOfRowsToStretchPitchogram:app.sai.frameRateOfSaiMovie:app.numberOfRowsOfPitchogram;
            ticklabelsForTimeAxisOfPitchogram = num2cell((indicesOfTicksForTimeAxisOfPitchogram-app.numberOfRowsToStretchPitchogram)/app.sai.frameRateOfSaiMovie);
            indicesOfTicksForTimeAxisOfPitchogram = horzcat(1,indicesOfTicksForTimeAxisOfPitchogram);
            ticklabelsForTimeAxisOfPitchogram = horzcat({'00000'},ticklabelsForTimeAxisOfPitchogram);
            
            yticks(appObjectForTargetUi.UIAxesForMoviePitchogram,indicesOfTicksForTimeAxisOfPitchogram)
            yticklabels(appObjectForTargetUi.UIAxesForMoviePitchogram,ticklabelsForTimeAxisOfPitchogram)
        end
        
        function SetTimeUIAxisForCompleteGrams(app,appObjectForTargetUi)
            indicesOfTicksForTimeAxisOfCompleteGrams = ReturnIndicesOfTicksForTimeAxis(app.completeCochleagram.timeAxisOfCompleteCochleagram, app.completeCochleagram.frameRateOfSaiMovie);
            ticklabelsForTimeAxisOfCompleteGrams = num2cell(app.completeCochleagram.timeAxisOfCompleteCochleagram(indicesOfTicksForTimeAxisOfCompleteGrams));
            ticklabelsForTimeAxisOfCompleteGrams(end) = num2cell(round(ticklabelsForTimeAxisOfCompleteGrams{end},1));
            
            xticks(appObjectForTargetUi.UIAxesForCompleteCochleagram,indicesOfTicksForTimeAxisOfCompleteGrams)
            xticklabels(appObjectForTargetUi.UIAxesForCompleteCochleagram,ticklabelsForTimeAxisOfCompleteGrams)
            
            ticklabelsForTimeAxisOfCompleteGrams(1) = {'00000'};
            
            yticks(appObjectForTargetUi.UIAxesForCompletePitchogram,indicesOfTicksForTimeAxisOfCompleteGrams)
            yticklabels(appObjectForTargetUi.UIAxesForCompletePitchogram,ticklabelsForTimeAxisOfCompleteGrams)
        end
        
        function UpdateTimeEditField(app,segmentNumber)
            timeDerivedFromSegment = round(segmentNumber/app.sai.frameRateOfSaiMovie,6);
            app.TimeEditField.Value = num2str(timeDerivedFromSegment);
        end
        
        function ShowSegmentOfSai(app,appObjectForTargetUi,segmentNumber)
            image(appObjectForTargetUi.UIAxesForSai, ...
                app.ScaleColormap ...
                * app.sai.stabilizedAuditoryImage(:,:,segmentNumber))
        end
        
        function ShowSegmentOfMovieCochleagram(app,appObjectForTargetUi,segmentNumber)
            image(appObjectForTargetUi.UIAxesForMovieCochleagram, ...
                app.ScaleColormap * app.COLORMAP_SCALE_FACTOR_FOR_COCHLEAGRAM ...
                * flip(app.movieCochleagram.cochleagramFromSaiMovie(:,:,segmentNumber),2))
        end
        
        function ShowSegmentOfMoviePitchogram(app,appObjectForTargetUi,segmentNumber)
            image(appObjectForTargetUi.UIAxesForMoviePitchogram, ...
                app.ScaleColormap * app.COLORMAP_SCALE_FACTOR_FOR_PITCHOGRAM ...
                * max(0,app.moviePitchogram.pitchogramFromSaiMovie(:,:,segmentNumber)))
        end
        
        function scaleFactor = ScaleColormap(app)
            scaleFactor = app.COLORMAP_SCALE_FACTOR_FOR_SAI * app.colormapScaleFactor;
        end        
        
        function UpdateLinesToShowPositionInCompleteGrams(app,segmentNumber)
            app.lineToShowPositionInCompleteCochleagram.XData = [segmentNumber segmentNumber];
            app.lineToShowPositionInCompletePitchogram.YData = [segmentNumber segmentNumber];
        end
    end
    
    methods (Access = public)
        
        function SetLimitsForUiAxesComponents(app,appObjectForTargetUi)
            appObjectForTargetUi.UIAxesForSai.XLim = [1 app.sai.totalWidthOfAllLayers];
            appObjectForTargetUi.UIAxesForMovieCochleagram.XLim = [1 app.sai.totalWidthOfAllLayers];
            appObjectForTargetUi.UIAxesForCompleteCochleagram.XLim = [1 length(app.completeCochleagram.timeAxisOfCompleteCochleagram)];
            appObjectForTargetUi.UIAxesForMoviePitchogram.XLim = [1 app.sai.totalWidthOfAllLayers];
            appObjectForTargetUi.UIAxesForCompletePitchogram.XLim = [1 length(app.completePitchogram.lagAxisInHzOfCompletePitchogram)];
            
            appObjectForTargetUi.UIAxesForSai.YLim = [1 app.sai.totalNumberOfChannels];
            appObjectForTargetUi.UIAxesForMovieCochleagram.YLim = [1 app.sai.totalNumberOfChannels];
            appObjectForTargetUi.UIAxesForCompleteCochleagram.YLim = [1 length(app.completeCochleagram.frequencyAxisOfCompleteCochleagram)];
            appObjectForTargetUi.UIAxesForMoviePitchogram.YLim = [1 app.numberOfRowsOfPitchogram];            
            appObjectForTargetUi.UIAxesForCompletePitchogram.YLim = [1 length(app.completePitchogram.timeAxisOfCompletePitchogram)];
        end
        
        function SetAxisForUIAxis(app,appObjectForTargetUi)
            SetFrequencyUIAxisForSaiAndCochleagrams(app,appObjectForTargetUi)
            SetLagUIAxisForSaiAndPitchograms(app,appObjectForTargetUi)
            SetTimeUIAxisForMovieCochleagram(app,appObjectForTargetUi)
            SetTimeUIAxisForMoviePitchogram(app,appObjectForTargetUi)
            SetTimeUIAxisForCompleteGrams(app,appObjectForTargetUi)
        end
        
        function SetColormap(app,appObjectForTargetUi)
            colormap(appObjectForTargetUi.UIAxesForSai,app.colormapMatrix)
            colormap(appObjectForTargetUi.UIAxesForMovieCochleagram,app.colormapMatrix)
            colormap(appObjectForTargetUi.UIAxesForMoviePitchogram,app.colormapMatrix)
            colormap(appObjectForTargetUi.UIAxesForCompleteCochleagram,app.colormapMatrix)
            colormap(appObjectForTargetUi.UIAxesForCompletePitchogram,app.colormapMatrix)
        end
        
        function ShowSegment(app,appObjectForTargetUi,segmentNumber)
            ShowSegmentOfSai(app,appObjectForTargetUi,segmentNumber)
            ShowSegmentOfMovieCochleagram(app,appObjectForTargetUi,segmentNumber)
            ShowSegmentOfMoviePitchogram(app,appObjectForTargetUi,segmentNumber)
        end
        
        function ShowCompleteCochleagramAndPitchogram(app,appObjectForTargetUi)
            image(appObjectForTargetUi.UIAxesForCompleteCochleagram, ...
                app.ScaleColormap * app.COLORMAP_SCALE_FACTOR_FOR_COCHLEAGRAM ...
                * app.completeCochleagram.completeCochleagram)
            image(appObjectForTargetUi.UIAxesForCompletePitchogram, ...
                app.ScaleColormap * app.COLORMAP_SCALE_FACTOR_FOR_PITCHOGRAM ...
                * app.completePitchogram.completePitchogram)
        end
        
        function SetLinesToShowPositionInCompleteGrams(app,appObjectForTargetUi,segmentNumber)
            appObjectForTargetUi.lineToShowPositionInCompleteCochleagram = line(appObjectForTargetUi.UIAxesForCompleteCochleagram);
            appObjectForTargetUi.lineToShowPositionInCompleteCochleagram.XData = [segmentNumber segmentNumber];
            appObjectForTargetUi.lineToShowPositionInCompleteCochleagram.YData = [1 length(app.completeCochleagram.frequencyAxisOfCompleteCochleagram)];
            appObjectForTargetUi.lineToShowPositionInCompleteCochleagram.Color = 'r';
            appObjectForTargetUi.lineToShowPositionInCompleteCochleagram.Marker = 'o';
            appObjectForTargetUi.lineToShowPositionInCompleteCochleagram.MarkerFaceColor = 'r';

            appObjectForTargetUi.lineToShowPositionInCompletePitchogram = line(appObjectForTargetUi.UIAxesForCompletePitchogram);
            appObjectForTargetUi.lineToShowPositionInCompletePitchogram.XData = [1 length(app.completePitchogram.lagAxisInHzOfCompletePitchogram)];
            appObjectForTargetUi.lineToShowPositionInCompletePitchogram.YData = [segmentNumber segmentNumber];
            appObjectForTargetUi.lineToShowPositionInCompletePitchogram.Color = 'r';
            appObjectForTargetUi.lineToShowPositionInCompletePitchogram.Marker = 'o';
            appObjectForTargetUi.lineToShowPositionInCompletePitchogram.MarkerFaceColor = 'r';            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLabel.Text = 'Initial UI creation finished.';
            
            ROOT_PATH_OF_AUDIIMAGE = fileparts(mfilename('fullpath'));
            addpath(ROOT_PATH_OF_AUDIIMAGE);
            addpath(fullfile(ROOT_PATH_OF_AUDIIMAGE,'model'));
            addpath(fullfile(ROOT_PATH_OF_AUDIIMAGE,'script'));
            addpath(fullfile(ROOT_PATH_OF_AUDIIMAGE,'helper'));

            ParametersToShowSai;
            
            app.pathToResults = fullfile(basePathToData, subfolderToResults);
            
            LoadSummaryFile(app)
            app.StatusLabel.Text = 'Ready.';
        end

        % Value changed function: SegmentSlider
        function SegmentSliderValueChanged(app, event)
            value = round(app.SegmentSlider.Value);
            app.SegmentSlider.Value = value;
            
            app.SegmentSpinner.Value = value;
            app.UpdateTimeEditField(value)
            
            ShowSegment(app,app,value)
            UpdateLinesToShowPositionInCompleteGrams(app,value)
        end

        % Value changed function: SegmentSpinner
        function SegmentSpinnerValueChanged(app, event)
            value = app.SegmentSpinner.Value;
            
            app.SegmentSlider.Value = value;
            app.UpdateTimeEditField(value)
            
            ShowSegment(app,app,value)
            UpdateLinesToShowPositionInCompleteGrams(app,value)
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            timeInSeconds = str2double(value);
            
            if timeInSeconds <= 0
                timeInSeconds = app.minimumTimeOfSaiMovieInSeconds;
            elseif timeInSeconds > app.maximumTimeOfSaiMovieInSeconds
                timeInSeconds = app.maximumTimeOfSaiMovieInSeconds;
            else
                timeInSeconds = round(timeInSeconds * app.sai.frameRateOfSaiMovie) / app.sai.frameRateOfSaiMovie;
            end
            
            app.TimeEditField.Value = num2str(timeInSeconds);
            
            segmentDerivedFromTime = timeInSeconds * app.sai.frameRateOfSaiMovie;
            app.SegmentSlider.Value = segmentDerivedFromTime;
            app.SegmentSpinner.Value = segmentDerivedFromTime;
            
            ShowSegment(app,app,segmentDerivedFromTime)
            UpdateLinesToShowPositionInCompleteGrams(app,segmentDerivedFromTime)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            app.colormapMatrix = ReturnColormapMatrix(value);
            
            SetFolderForSaiSegments(app)
            SetColormap(app,app)
            
            app.StatusLabel.Text = ['Colormap set to "' value '".'];
        end

        % Button pushed function: SaveActualSegmentButton
        function SaveActualSegmentButtonPushed(app, event)
            namePatternForSaiSegments = 'segment%05d.png';
            
            if ~isfolder(app.subfolderForSaiSegments)
                mkdir(app.subfolderForSaiSegments)
            end
            if ~isfolder(app.fullFolderForSaiSegmentsOfAudioFile)
                mkdir(app.fullFolderForSaiSegmentsOfAudioFile)
            end
            fullFilenameForSaiSegmentsOfAudioFile = fullfile(app.fullFolderForSaiSegmentsOfAudioFile,sprintf(namePatternForSaiSegments, app.SegmentSpinner.Value));
            
            exportapp(app.ShowSaiUIFigure,fullFilenameForSaiSegmentsOfAudioFile)
            app.StatusLabel.Text = ['Actual segment saved to: ' fullFilenameForSaiSegmentsOfAudioFile];            
        end

        % Button pushed function: OpenSummaryFileButton
        function OpenSummaryFileButtonPushed(app, event)
            LoadSummaryFile(app)
        end

        % Value changed function: SegmentStepSizeSpinner
        function SegmentStepSizeSpinnerValueChanged(app, event)
            value = app.SegmentStepSizeSpinner.Value;
            app.SegmentSpinner.Step = value;
            
            app.StatusLabel.Text = ['Segment step size set to ' num2str(value) '.'];
        end

        % Button pushed function: OpenNewWindowButton
        function OpenNewWindowButtonPushed(app, event)
            ShowSai;
            app.StatusLabel.Text = 'New SAI analyzer window opened.';
        end

        % Value changed function: ColormapScaleFactorSpinner
        function ColormapScaleFactorSpinnerValueChanged(app, event)
            value = app.ColormapScaleFactorSpinner.Value;
            app.colormapScaleFactor = value;
            
            ShowSegment(app,app,app.SegmentSpinner.Value)
            ShowCompleteCochleagramAndPitchogram(app,app)
            SetLinesToShowPositionInCompleteGrams(app,app,app.SegmentSpinner.Value)
            
            app.StatusLabel.Text = ['Colormap scale factor set to ' num2str(value) '.'];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.numberOfRowsToStretchPitchogram = app.StretchEditField.Value;
            app.numberOfRowsOfPitchogram = app.DurationEditField.Value * app.sai.frameRateOfSaiMovie + app.numberOfRowsToStretchPitchogram;
            
            app.StatusLabel.Text = 'SAI_RunLayered_toRecomputeMoviePitchogramInShowSai | Segment computation: started';
            app.moviePitchogram.pitchogramFromSaiMovie = SAI_RunLayered_toRecomputeMoviePitchogramInShowSai( ...
                app.numberOfRowsOfPitchogram, ...
                app.numberOfRowsToStretchPitchogram, ...
                app.sai ...
                );
            app.StatusLabel.Text = 'SAI_RunLayered_toRecomputeMoviePitchogramInShowSai | Segment computation: finished';
            
            SetTimeUIAxisForMoviePitchogram(app,app)

            % COPY from SetLimitsForUiComponents
            app.UIAxesForMoviePitchogram.YLim = [1 app.numberOfRowsOfPitchogram];            

            ShowSegmentOfMoviePitchogram(app,app,app.SegmentSpinner.Value)
            
            app.StatusLabel.Text = 'Recompute "movie" pitchogram done.';
        end

        % Value changed function: LowerLimitOfLagAxisEditField
        function LowerLimitOfLagAxisEditFieldValueChanged(app, event)
            value = app.LowerLimitOfLagAxisEditField.Value;
            app.LowerLimitOfLagAxisEditField.RoundFractionalValues = false;
            
            % principle to change axis taken from SetLagUIAxisForSaiAndPitchograms
            [~, indexOfValueOnLagAxis] = min(abs((app.sai.lagAxisInHzOfSaiAndPitchogram - value)'));
            app.UIAxesForSai.XLim(1) = indexOfValueOnLagAxis;
            app.UIAxesForMoviePitchogram.XLim(1) = indexOfValueOnLagAxis;
            app.UIAxesForCompletePitchogram.XLim(1) = indexOfValueOnLagAxis;
            
            app.StatusLabel.Text = ['Lower limit of lag axis set to ' num2str(value) ' Hz.'];
        end

        % Value changed function: UpperLimitOfFrequencyAxisEditField
        function UpperLimitOfFrequencyAxisEditFieldValueChanged(app, event)
            value = app.UpperLimitOfFrequencyAxisEditField.Value;

            % principle to change axis taken from SetFrequencyUIAxisForSaiAndCochleagrams
            [~, indexOfValueOnFrequencyAxis] = min(abs((app.sai.frequencyAxisOfSaiAndCochleagram' - value)'));
            app.UIAxesForSai.YLim(1) = indexOfValueOnFrequencyAxis;
            app.UIAxesForMovieCochleagram.YLim(1) = indexOfValueOnFrequencyAxis;
            app.UIAxesForCompleteCochleagram.YLim(1) = indexOfValueOnFrequencyAxis;
            
            app.StatusLabel.Text = ['Upper limit of frequency axis set to ' num2str(value) ' Hz.'];
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            SetLimitsForUiComponents(app)
            SetValueOfLimitEditFieldsToDefault(app)
            
            app.StatusLabel.Text = 'All axes resetted to default view.';
        end

        % Button pushed function: CopyFiguresToSeparateWindowButton
        function CopyFiguresToSeparateWindowButtonPushed(app, event)
            ShowSaiExportDiagrams(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShowSaiUIFigure = uifigure('Visible', 'off');
            app.ShowSaiUIFigure.Name = 'Show and analyze SAI in detail';
            app.UIAxesForSai = uiaxes(app.ShowSaiUIFigure);
            title(app.UIAxesForSai, 'SAI')
            xlabel(app.UIAxesForSai, 'log-compressed lag in Hz')
            ylabel(app.UIAxesForSai, 'Frequency in Hz')
            zlabel(app.UIAxesForSai, 'Z')
            app.UIAxesForSai.YAxisLocation = 'right';
            app.ControlpanelPanel = uipanel(app.ShowSaiUIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.FilenameLabel = uilabel(app.ControlpanelPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.LabelForFilename = uilabel(app.ControlpanelPanel);
            app.SegmentSliderLabel = uilabel(app.ControlpanelPanel);
            app.SegmentSliderLabel.Text = 'Segment';
            app.SegmentSlider = uislider(app.ControlpanelPanel);
            app.SegmentSlider.Limits = [1 100];
            app.SegmentSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentSliderValueChanged, true);
            app.SegmentSlider.Value = 1;
            app.SegmentSpinnerLabel = uilabel(app.ControlpanelPanel);
            app.SegmentSpinnerLabel.Text = 'Segment';
            app.SegmentSpinner = uispinner(app.ControlpanelPanel);
            app.SegmentSpinner.Limits = [1 Inf];
            app.SegmentSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentSpinnerValueChanged, true);
            app.SegmentSpinner.Value = 1;
            app.TimeLabel = uilabel(app.ControlpanelPanel);
            app.TimeLabel.Text = '= Time';
            app.TimeEditField = uieditfield(app.ControlpanelPanel, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.sLabel = uilabel(app.ControlpanelPanel);
            app.sLabel.Text = 's';
            app.ColormapDropDownLabel = uilabel(app.ControlpanelPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ControlpanelPanel);
            app.ColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'gray (inverted)', 'bone', 'copper', 'pink', 'jet', 'artemis'};
            app.ColormapDropDown.ItemsData = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'grayInverted', 'bone', 'copper', 'pink', 'jet', 'artemis'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'turbo';
            app.SaveActualSegmentButton = uibutton(app.ControlpanelPanel, 'push');
            app.SaveActualSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SaveActualSegmentButtonPushed, true);
            app.SaveActualSegmentButton.Text = 'Save actual segment as PNG file';
            app.OpenSummaryFileButton = uibutton(app.ControlpanelPanel, 'push');
            app.OpenSummaryFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSummaryFileButtonPushed, true);
            app.OpenSummaryFileButton.Text = 'Load summary file...';
            app.SegmentstepsizeSpinnerLabel = uilabel(app.ControlpanelPanel);
            app.SegmentstepsizeSpinnerLabel.Text = 'Segment step size';
            app.SegmentStepSizeSpinner = uispinner(app.ControlpanelPanel);
            app.SegmentStepSizeSpinner.Limits = [1 Inf];
            app.SegmentStepSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentStepSizeSpinnerValueChanged, true);
            app.SegmentStepSizeSpinner.Value = 1;
            app.OpenNewWindowButton = uibutton(app.ControlpanelPanel, 'push');
            app.OpenNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenNewWindowButtonPushed, true);
            app.OpenNewWindowButton.Text = 'Open new window';
            app.ColormapscalefactorLabel = uilabel(app.ControlpanelPanel);
            app.ColormapscalefactorLabel.Text = 'Colormap scale factor';
            app.ColormapScaleFactorSpinner = uispinner(app.ControlpanelPanel);
            app.ColormapScaleFactorSpinner.Step = 0.1;
            app.ColormapScaleFactorSpinner.Limits = [0.1 2];
            app.ColormapScaleFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ColormapScaleFactorSpinnerValueChanged, true);
            app.ColormapScaleFactorSpinner.Value = 1.3;
            app.RecomputeMoviePitchogramPanel = uipanel(app.ControlpanelPanel);
            app.RecomputeMoviePitchogramPanel.Title = 'Recompute "movie" pitchogram';
            app.StretchEditFieldLabel = uilabel(app.RecomputeMoviePitchogramPanel);
            app.StretchEditFieldLabel.Text = 'Stretch';
            app.StretchEditField = uieditfield(app.RecomputeMoviePitchogramPanel, 'numeric');
            app.StretchEditField.Limits = [1 10];
            app.StretchEditField.RoundFractionalValues = 'on';
            app.StretchEditField.Value = 1;
            app.RunButton = uibutton(app.RecomputeMoviePitchogramPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DurationEditFieldLabel = uilabel(app.RecomputeMoviePitchogramPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.RecomputeMoviePitchogramPanel, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 1;
            app.pxLabel = uilabel(app.RecomputeMoviePitchogramPanel);
            app.pxLabel.Text = 'px';
            app.sLabel_2 = uilabel(app.RecomputeMoviePitchogramPanel);
            app.sLabel_2.Text = 's';
            app.lowerlimitoflagaxisEditFieldLabel = uilabel(app.ControlpanelPanel);
            app.lowerlimitoflagaxisEditFieldLabel.Text = 'lower limit of lag axis';
            app.LowerLimitOfLagAxisEditField = uieditfield(app.ControlpanelPanel, 'numeric');
            app.LowerLimitOfLagAxisEditField.Limits = [0 Inf];
            app.LowerLimitOfLagAxisEditField.RoundFractionalValues = 'on';
            app.LowerLimitOfLagAxisEditField.ValueDisplayFormat = '%11.3g';
            app.LowerLimitOfLagAxisEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitOfLagAxisEditFieldValueChanged, true);
            app.LowerLimitOfLagAxisHzLabel = uilabel(app.ControlpanelPanel);
            app.LowerLimitOfLagAxisHzLabel.Text = 'Hz';
            app.upperlimitoffrequencyaxisLabel = uilabel(app.ControlpanelPanel);
            app.upperlimitoffrequencyaxisLabel.Text = 'upper limit of frequency axis';
            app.UpperLimitOfFrequencyAxisEditField = uieditfield(app.ControlpanelPanel, 'numeric');
            app.UpperLimitOfFrequencyAxisEditField.Limits = [100 Inf];
            app.UpperLimitOfFrequencyAxisEditField.ValueDisplayFormat = '%11.5g';
            app.UpperLimitOfFrequencyAxisEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitOfFrequencyAxisEditFieldValueChanged, true);
            app.UpperLimitOfFrequencyAxisEditField.Value = 20000;
            app.UpperLimitOfFrequencyAxisHzLabel = uilabel(app.ControlpanelPanel);
            app.UpperLimitOfFrequencyAxisHzLabel.Text = 'Hz';
            app.ResetAxesButton = uibutton(app.ControlpanelPanel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset axes';
            app.CopyFiguresToSeparateWindowButton = uibutton(app.ControlpanelPanel, 'push');
            app.CopyFiguresToSeparateWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFiguresToSeparateWindowButtonPushed, true);
            app.CopyFiguresToSeparateWindowButton.Text = 'Copy figures to separate window';
            app.PitchogramTabGroup = uitabgroup(app.ShowSaiUIFigure);
            app.MoviePitchogramTab = uitab(app.PitchogramTabGroup);
            app.MoviePitchogramTab.Title = '"movie" pitchogram';
            app.UIAxesForMoviePitchogram = uiaxes(app.MoviePitchogramTab);
            title(app.UIAxesForMoviePitchogram, 'Pitchogram like in the SAI movie')
            xlabel(app.UIAxesForMoviePitchogram, 'log-compressed lag in Hz')
            ylabel(app.UIAxesForMoviePitchogram, 'Delta Time in s')
            zlabel(app.UIAxesForMoviePitchogram, 'Z')
            app.UIAxesForMoviePitchogram.YAxisLocation = 'right';
            app.CompletePitchogramTab = uitab(app.PitchogramTabGroup);
            app.CompletePitchogramTab.Title = 'complete pitchogram';
            app.UIAxesForCompletePitchogram = uiaxes(app.CompletePitchogramTab);
            title(app.UIAxesForCompletePitchogram, 'Pitchogram')
            xlabel(app.UIAxesForCompletePitchogram, 'log-compressed lag in Hz')
            ylabel(app.UIAxesForCompletePitchogram, 'Time in s')
            zlabel(app.UIAxesForCompletePitchogram, 'Z')
            app.UIAxesForCompletePitchogram.YAxisLocation = 'right';
            app.CochleagramTabGroup = uitabgroup(app.ShowSaiUIFigure);
            app.MovieCochleagramTab = uitab(app.CochleagramTabGroup);
            app.MovieCochleagramTab.Title = '"movie" cochleagram';
            app.UIAxesForMovieCochleagram = uiaxes(app.MovieCochleagramTab);
            title(app.UIAxesForMovieCochleagram, 'Cochleagram like in the SAI movie')
            xlabel(app.UIAxesForMovieCochleagram, 'Delta Time in s')
            ylabel(app.UIAxesForMovieCochleagram, 'Frequency in Hz')
            zlabel(app.UIAxesForMovieCochleagram, 'Z')
            app.UIAxesForMovieCochleagram.YAxisLocation = 'right';
            app.CompleteCochleagramTab = uitab(app.CochleagramTabGroup);
            app.CompleteCochleagramTab.Title = 'complete cochleagram';
            app.UIAxesForCompleteCochleagram = uiaxes(app.CompleteCochleagramTab);
            title(app.UIAxesForCompleteCochleagram, 'Cochleagram')
            xlabel(app.UIAxesForCompleteCochleagram, 'Time in s')
            ylabel(app.UIAxesForCompleteCochleagram, 'Frequency in Hz')
            zlabel(app.UIAxesForCompleteCochleagram, 'Z')
            app.UIAxesForCompleteCochleagram.YAxisLocation = 'right';
            app.StatusLabel = uilabel(app.ShowSaiUIFigure);
            app.StatusLabel.Text = 'Status bar';
            % Show the figure after all components are created
            app.ShowSaiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowSai

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShowSaiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowSaiUIFigure)
        end
    end
end