classdef ShowSaiExportDiagrams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowSaiExportDiagramsUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UpdateButton                   matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DiagramLabel                   matlab.ui.control.Label
        WindowLabel                    matlab.ui.control.Label
        pxLabel_4                      matlab.ui.control.Label
        pxLabel_3                      matlab.ui.control.Label
        pxLabel_2                      matlab.ui.control.Label
        pxLabel_1                      matlab.ui.control.Label
        HeightLabel_2                  matlab.ui.control.Label
        HeightOfDiagramEditField       matlab.ui.control.NumericEditField
        WidthLabel_2                   matlab.ui.control.Label
        WidthOfDiagramEditField        matlab.ui.control.NumericEditField
        HeightLabel                    matlab.ui.control.Label
        HeightSpinner                  matlab.ui.control.Spinner
        WidthLabel                     matlab.ui.control.Label
        WidthSpinner                   matlab.ui.control.Spinner
        AuditoryImageTabGroup          matlab.ui.container.TabGroup
        SAITab                         matlab.ui.container.Tab
        UIAxesForSai                   matlab.ui.control.UIAxes
        MovieCochleagramTab            matlab.ui.container.Tab
        UIAxesForMovieCochleagram      matlab.ui.control.UIAxes
        CompleteCochleagramTab         matlab.ui.container.Tab
        UIAxesForCompleteCochleagram   matlab.ui.control.UIAxes
        moviepitchogramTab             matlab.ui.container.Tab
        UIAxesForMoviePitchogram       matlab.ui.control.UIAxes
        pitchogramTab                  matlab.ui.container.Tab
        UIAxesForCompletePitchogram    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp
    end
    
    properties (Access = public)
        lineToShowPositionInCompleteCochleagram
        lineToShowPositionInCompletePitchogram
    end
    
    methods (Access = private)
        
        function UpdateSpinnerValues(app)
            app.WidthSpinner.Value = app.ShowSaiExportDiagramsUIFigure.Position(3);
            app.HeightSpinner.Value = app.ShowSaiExportDiagramsUIFigure.Position(4);            
        end
                
        function UpdateWidthOfDiagram(app)
            app.WidthOfDiagramEditField.Value = app.UIAxesForSai.Position(3) * 1.56;
        end
        
        function UpdateHeightOfDiagram(app)
            app.HeightOfDiagramEditField.Value = app.UIAxesForSai.Position(4) * 1.55;
        end
        
        function UpdateLimitsForUiAxesComponents(app)
            app.UIAxesForSai.XLim(1) = app.callingApp.UIAxesForSai.XLim(1);
            app.UIAxesForMoviePitchogram.XLim(1) = app.callingApp.UIAxesForMoviePitchogram.XLim(1);
            app.UIAxesForCompletePitchogram.XLim(1) = app.callingApp.UIAxesForCompletePitchogram.XLim(1);
            
            app.UIAxesForSai.YLim(1) = app.callingApp.UIAxesForSai.YLim(1);
            app.UIAxesForMovieCochleagram.YLim(1) = app.callingApp.UIAxesForMovieCochleagram.YLim(1);
            app.UIAxesForCompleteCochleagram.YLim(1) = app.callingApp.UIAxesForCompleteCochleagram.YLim(1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
            
            UpdateSpinnerValues(app)
            UpdateWidthOfDiagram(app)
            UpdateHeightOfDiagram(app)
            
            app.callingApp.SetLimitsForUiAxesComponents(app)
            UpdateLimitsForUiAxesComponents(app)
            app.callingApp.SetAxisForUIAxis(app)
            app.callingApp.SetColormap(app)
            app.callingApp.ShowSegment(app,app.callingApp.SegmentSpinner.Value)
            app.callingApp.ShowCompleteCochleagramAndPitchogram(app)
            app.callingApp.SetLinesToShowPositionInCompleteGrams(app,app.callingApp.SegmentSpinner.Value)
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            value = app.WidthSpinner.Value;
            app.ShowSaiExportDiagramsUIFigure.Position(3) = value;
            UpdateWidthOfDiagram(app)
        end

        % Value changed function: HeightSpinner
        function HeightSpinnerValueChanged(app, event)
            value = app.HeightSpinner.Value;
            app.ShowSaiExportDiagramsUIFigure.Position(4) = value;
            UpdateHeightOfDiagram(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            UpdateSpinnerValues(app)
            UpdateWidthOfDiagram(app)
            UpdateHeightOfDiagram(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.callingApp.SetLimitsForUiAxesComponents(app)
            UpdateLimitsForUiAxesComponents(app)
            app.callingApp.SetAxisForUIAxis(app)
            app.callingApp.SetColormap(app)
            app.callingApp.ShowSegment(app,app.callingApp.SegmentSpinner.Value)
            app.callingApp.ShowCompleteCochleagramAndPitchogram(app)
            app.callingApp.SetLinesToShowPositionInCompleteGrams(app,app.callingApp.SegmentSpinner.Value)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShowSaiExportDiagramsUIFigure = uifigure('Visible', 'off');
            app.ShowSaiExportDiagramsUIFigure.Name = 'Export SAI analysis to user-defined size';
            app.GridLayout = uigridlayout(app.ShowSaiExportDiagramsUIFigure);
            app.AuditoryImageTabGroup = uitabgroup(app.GridLayout);
            app.SAITab = uitab(app.AuditoryImageTabGroup);
            app.SAITab.Title = 'SAI';
            app.UIAxesForSai = uiaxes(app.SAITab);
            title(app.UIAxesForSai, 'SAI')
            xlabel(app.UIAxesForSai, 'log-compressed lag in Hz')
            ylabel(app.UIAxesForSai, 'Frequency in Hz')
            zlabel(app.UIAxesForSai, 'Z')
            app.UIAxesForSai.YAxisLocation = 'right';
            app.MovieCochleagramTab = uitab(app.AuditoryImageTabGroup);
            app.MovieCochleagramTab.Title = '"movie" cochleagram';
            app.UIAxesForMovieCochleagram = uiaxes(app.MovieCochleagramTab);
            title(app.UIAxesForMovieCochleagram, 'Cochleagram like in the SAI movie')
            xlabel(app.UIAxesForMovieCochleagram, 'Delta Time in s')
            ylabel(app.UIAxesForMovieCochleagram, 'Frequency in Hz')
            zlabel(app.UIAxesForMovieCochleagram, 'Z')
            app.UIAxesForMovieCochleagram.YAxisLocation = 'right';
            app.CompleteCochleagramTab = uitab(app.AuditoryImageTabGroup);
            app.CompleteCochleagramTab.Title = 'cochleagram';
            app.UIAxesForCompleteCochleagram = uiaxes(app.CompleteCochleagramTab);
            title(app.UIAxesForCompleteCochleagram, 'Cochleagram')
            xlabel(app.UIAxesForCompleteCochleagram, 'Time in s')
            ylabel(app.UIAxesForCompleteCochleagram, 'Frequency in Hz')
            zlabel(app.UIAxesForCompleteCochleagram, 'Z')
            app.UIAxesForCompleteCochleagram.YAxisLocation = 'right';
            app.moviepitchogramTab = uitab(app.AuditoryImageTabGroup);
            app.moviepitchogramTab.Title = '"movie" pitchogram';
            app.UIAxesForMoviePitchogram = uiaxes(app.moviepitchogramTab);
            title(app.UIAxesForMoviePitchogram, 'Pitchogram like in the SAI movie')
            xlabel(app.UIAxesForMoviePitchogram, 'log-compressed lag in Hz')
            ylabel(app.UIAxesForMoviePitchogram, 'Delta Time in s')
            zlabel(app.UIAxesForMoviePitchogram, 'Z')
            app.UIAxesForMoviePitchogram.YAxisLocation = 'right';
            app.pitchogramTab = uitab(app.AuditoryImageTabGroup);
            app.pitchogramTab.Title = 'pitchogram';
            app.UIAxesForCompletePitchogram = uiaxes(app.pitchogramTab);
            title(app.UIAxesForCompletePitchogram, 'Pitchogram')
            xlabel(app.UIAxesForCompletePitchogram, 'log-compressed lag in Hz')
            ylabel(app.UIAxesForCompletePitchogram, 'Time in s')
            zlabel(app.UIAxesForCompletePitchogram, 'Z')
            app.UIAxesForCompletePitchogram.YAxisLocation = 'right';
            app.WidthSpinner = uispinner(app.GridLayout);
            app.WidthSpinner.Limits = [372 Inf];
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 372;
            app.WidthLabel = uilabel(app.GridLayout);
            app.WidthLabel.Text = 'Width';
            app.HeightSpinner = uispinner(app.GridLayout);
            app.HeightSpinner.Limits = [310 Inf];
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightSpinnerValueChanged, true);
            app.HeightSpinner.Value = 310;
            app.HeightLabel = uilabel(app.GridLayout);
            app.HeightLabel.Text = {'Height'; ''};
            app.WidthOfDiagramEditField = uieditfield(app.GridLayout, 'numeric');
            app.WidthOfDiagramEditField.RoundFractionalValues = 'on';
            app.WidthOfDiagramEditField.Editable = 'off';
            app.WidthLabel_2 = uilabel(app.GridLayout);
            app.WidthLabel_2.Text = 'Width';
            app.HeightOfDiagramEditField = uieditfield(app.GridLayout, 'numeric');
            app.HeightOfDiagramEditField.RoundFractionalValues = 'on';
            app.HeightOfDiagramEditField.Editable = 'off';
            app.HeightLabel_2 = uilabel(app.GridLayout);
            app.HeightLabel_2.Text = 'Height';
            app.pxLabel_1 = uilabel(app.GridLayout);
            app.pxLabel_1.Text = 'px';
            app.pxLabel_2 = uilabel(app.GridLayout);
            app.pxLabel_2.Text = 'px';
            app.pxLabel_3 = uilabel(app.GridLayout);
            app.pxLabel_3.Text = 'px';
            app.pxLabel_4 = uilabel(app.GridLayout);
            app.pxLabel_4.Text = 'px';
            app.WindowLabel = uilabel(app.GridLayout);
            app.WindowLabel.Text = 'Window';
            app.DiagramLabel = uilabel(app.GridLayout);
            app.DiagramLabel.Text = 'Diagram';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.UpdateButton = uibutton(app.GridLayout, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.ShowSaiExportDiagramsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowSaiExportDiagrams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShowSaiExportDiagramsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowSaiExportDiagramsUIFigure)
        end
    end
end